classdef ImageProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BasicTab                        matlab.ui.container.Tab
        ConverttoGrayButton             matlab.ui.control.Button
        ChooseatransformationDropDown   matlab.ui.control.DropDown
        ChooseatransformationDropDownLabel  matlab.ui.control.Label
        ChooseanimageButton_3           matlab.ui.control.Button
        UIAxes13_2                      matlab.ui.control.UIAxes
        UIAxes13                        matlab.ui.control.UIAxes
        IntensityTransformationTab      matlab.ui.container.Tab
        ChooseanenhansmentDropDown      matlab.ui.control.DropDown
        ChooseanenhansmentDropDownLabel  matlab.ui.control.Label
        ChooseanimageButton_2           matlab.ui.control.Button
        UIAxes14_2                      matlab.ui.control.UIAxes
        UIAxes14                        matlab.ui.control.UIAxes
        HistogramTab                    matlab.ui.container.Tab
        EqualizationgrayButton          matlab.ui.control.Button
        EqualizationRGBButton           matlab.ui.control.Button
        ChooseanimageButton_4           matlab.ui.control.Button
        UIAxes12                        matlab.ui.control.UIAxes
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        SpatialdomanifiltersTab         matlab.ui.container.Tab
        chooseafilterDropDown           matlab.ui.control.DropDown
        chooseafilterDropDownLabel      matlab.ui.control.Label
        chooseanimageButton             matlab.ui.control.Button
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        FourierdomainfiltersTab         matlab.ui.container.Tab
        chooseasharpningfilterDropDown  matlab.ui.control.DropDown
        chooseasharpningfilterDropDownLabel  matlab.ui.control.Label
        TransformtoFourierDomainButton  matlab.ui.control.Button
        chooseasmoothingfilterDropDown  matlab.ui.control.DropDown
        chooseasmoothingfilterDropDownLabel  matlab.ui.control.Label
        chooseanimageButton_3           matlab.ui.control.Button
        modifiedImage_2                 matlab.ui.control.UIAxes
        modifiedImage                   matlab.ui.control.UIAxes
        originalImage                   matlab.ui.control.UIAxes
        NoiseTab                        matlab.ui.container.Tab
        ChooseanoiseremovalDropDown     matlab.ui.control.DropDown
        ChooseanoiseremovalDropDownLabel  matlab.ui.control.Label
        ChooseanoiseDropDown            matlab.ui.control.DropDown
        ChooseanoiseDropDownLabel       matlab.ui.control.Label
        ChooseanimageButton             matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        MorphologicalTab                matlab.ui.container.Tab
        chooseafilterDropDown_2         matlab.ui.control.DropDown
        chooseafilterDropDown_2Label    matlab.ui.control.Label
        chooseanimageButton_2           matlab.ui.control.Button
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        SegmentationTab                 matlab.ui.container.Tab
        ApplyThresholdingButton         matlab.ui.control.Button
        ChooseafilterforEdgeDetectionDropDown  matlab.ui.control.DropDown
        ChooseafilterforEdgeDetectionDropDownLabel  matlab.ui.control.Label
        chooseanimageButton_4           matlab.ui.control.Button
        modifiedImage2_2                matlab.ui.control.UIAxes
        modifiedImage2                  matlab.ui.control.UIAxes
        originalImage2                  matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        selectedNoise 
        saltPepper
        gaussian
        aliasingNoise
        periodicNoise
        image;
       OriginalImage  
        EqualizedImage;
        EnhancedImage;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseanimageButton
        function ChooseanimageButtonPushed(app, event)

            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            app.image=imread(filename);
            imshow(app.image,'Parent',app.UIAxes);

        end

        % Value changed function: chooseafilterDropDown
        function chooseafilterDropDownValueChanged(app, event)
            J=app.image;
            if size(J,3)==3
                J=rgb2gray(J);
            end
             J=im2double(J);
            value = app.chooseafilterDropDown.Value;
            switch value
                case 'Gaussian filter'
                     f=fspecial('gaussian',[9 9],5);
                     g=imfilter(J,f);
                     imshow(g,'Parent',app.UIAxes5);
                case 'Laplacian filter'
                     f=fspecial('laplacian');
                     x=imfilter(J,f);
                     l=x+J;
                     imshow(l,'Parent',app.UIAxes5);
                case 'Average filter'
                   f=fspecial('average',[9 9]);
                   a=imfilter(J,f);
                   imshow(a,'Parent',app.UIAxes5);
               case 'Sobel filter'
                   f=fspecial('sobel');
                   f1=f';
                   x=imfilter(J,f);
                   x1=imfilter(J,f1);
                   x2=abs(x)+abs(x1);
                   s=x2+J;
                   imshow(s,'Parent',app.UIAxes5);
                case 'Prewitt filter'
                    f=fspecial("prewitt");
                    f1=f';
                    x=imfilter(J,f);
                    x1=imfilter(J,f1);
                    x2=abs(x)+abs(x1);
                    p=x2+J;
                    imshow(p,'Parent',app.UIAxes5);
            end
        end

        % Button pushed function: chooseanimageButton
        function chooseanimageButtonPushed(app, event)
        [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            app.image=imread(filename);
            imshow(app.image,'Parent',app.UIAxes4);
        end

        % Value changed function: chooseafilterDropDown_2
        function chooseafilterDropDown_2ValueChanged(app, event)
            J=app.image;

            if size(J,3)==3
                J=rgb2gray(J);
            end
            J=imbinarize(J);
            imshow(J,'Parent',app.UIAxes7);
            se=strel('arbitrary',[0 1 0,1 1 1, 0 1 0]);
            value = app.chooseafilterDropDown_2.Value;
             switch value
                case 'Dilation'
                     d= imdilate(J,se);
                     imshow(d,'Parent',app.UIAxes8);
                case 'Erosion'
                     e=imerode(J,se);
                     imshow(e,'Parent',app.UIAxes8);
                case 'Opening'
                     o=imopen(J,se);
                     imshow(o,'Parent',app.UIAxes8);
                case 'Closing'
                     c=imclose(J,se);
                     imshow(c,'Parent',app.UIAxes8);
             end
        end

        % Button pushed function: chooseanimageButton_2
        function chooseanimageButton_2Pushed(app, event)

            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            app.image =imread(filename);
            imshow(app.image,'Parent',app.UIAxes6); 
        end

        % Value changed function: ChooseanoiseDropDown
        function ChooseanoiseDropDownValueChanged(app, event)
           J=app.image;
            if size(J,3)==3
                J=rgb2gray(J);
            end
            J=im2double(J);
            value = app.ChooseanoiseDropDown.Value;
            app.selectedNoise = value;
             switch value
                case 'Salt & Peppar'
                    app.saltPepper =imnoise(J,'salt & pepper',0.02);
                    imshow(app.saltPepper,'Parent',app.UIAxes2);

                case 'Gaussian'
                    app.gaussian =imnoise(J,'gaussian');
                    imshow(app.gaussian ,'Parent',app.UIAxes2);

                 case 'Aliasing Noise'
                   % Fourier Transform of the original image
                   imgfft = fftshift(fft2(J));

                   % Add vertical periodic noise in the Fourier domain
                   [rows, cols] = size(J);
                   crow = round(rows / 2);
                   ccol = round(cols / 2);

                   % Define noise amplitude
                   noise_amplitude = 4000;

                   % Add noise peaks at specific horizontal frequencies (introducing vertical noise)
                   imgfft(crow, ccol - 30) = imgfft(crow, ccol - 30) + noise_amplitude;
                   imgfft(crow, ccol + 30) = imgfft(crow, ccol + 30) + noise_amplitude;

                   % Inverse Fourier Transform to get the noisy image
                  app.aliasingNoise = real(ifft2(ifftshift(imgfft)));

                   imshow(app.aliasingNoise,'Parent',app.UIAxes2);

               case 'Periodic'
                  % Add periodic noise in the Fourier domain
                   % Get the size of the image
                   [rows, cols] = size(J);

                   % Compute the Fourier Transform of the original image
                   img_fft = fftshift(fft2(J));

                   % Define the noise amplitude
                   noise_amplitude = 4000;

                   D0 = 52;

                   % Get the center of the image in the Fourier domain
                   crow = round(rows / 2);
                   ccol = round(cols / 2);

                   % Add noise peaks at specific locations aligned with the filter's center frequency (D0)
                   % Diagonal noise points
                   img_fft(crow - D0, ccol - D0) = img_fft(crow - D0, ccol - D0) + noise_amplitude;
                   img_fft(crow - D0, ccol + D0) = img_fft(crow - D0, ccol + D0) + noise_amplitude;
                   img_fft(crow + D0, ccol - D0) = img_fft(crow + D0, ccol - D0) + noise_amplitude;
                   img_fft(crow + D0, ccol + D0) = img_fft(crow + D0, ccol + D0) + noise_amplitude;

                   % Horizontal and vertical noise points
                   img_fft(crow, ccol - D0) = img_fft(crow, ccol - D0) + noise_amplitude;
                   img_fft(crow, ccol + D0) = img_fft(crow, ccol + D0) + noise_amplitude;
                   img_fft(crow - D0, ccol) = img_fft(crow - D0, ccol) + noise_amplitude;
                   img_fft(crow + D0, ccol) = img_fft(crow + D0, ccol) + noise_amplitude;

                   % Inverse Fourier Transform to get the noisy image in the spatial domain
                   app.periodicNoise = real(ifft2(ifftshift(img_fft)));


                   imshow( app.periodicNoise,'Parent',app.UIAxes2);
            end
           
        end

        % Value changed function: ChooseanoiseremovalDropDown
        function ChooseanoiseremovalDropDownValueChanged(app, event)

            value = app.ChooseanoiseremovalDropDown.Value;
            switch app.selectedNoise
                case 'Salt & Peppar'
                      noisyImg = app.saltPepper;

                case 'Gaussian'
                   noisyImg = app.gaussian;

                case 'Aliasing Noise'
                     noisyImg = app.aliasingNoise;

                 case 'Periodic'
                     noisyImg = app.periodicNoise;
            end

            switch value
                case 'Median filter'
                    e=medfilt2(noisyImg,[3 3]);
                    imshow(e,'Parent',app.UIAxes3);
                case 'Geometric mean filter'
                   paddedimage=padarray(noisyImg, [1 1], "replicate");
                   [rows, cols] = size(noisyImg);
                   result = zeros(rows, cols);
                   % Apply the midpoint filter
                   for i = 2 : rows-1
                       for j = 2 : cols-1
                           neighbors=paddedimage(i-1:i+1, j-1:j+1);
       
                           result(i-1,j-1)=prod(neighbors(:))^(1/numel(neighbors));
                       end
                   end
                  
                    imshow(result,'Parent',app.UIAxes3);
                case 'Gaussian band reject filter'
                    % Fourier Transform of the noisy image
                    noisy_img_fft = fftshift(fft2(noisyImg));
                    % Get image dimensions
                    [rows, cols] = size(noisyImg);
                    crow = round(rows / 2);
                    ccol = round(cols / 2);
                    D0 = 52;
                    W = 15;
                    H = zeros(rows, cols);
                    % Create the Gaussian Band-Reject Filter
                    for u = 1:rows
                        for v = 1:cols
                            % Compute the distance from the center
                            D = sqrt((u - crow).^2 + (v - ccol)^2);
                            % Apply the Gaussian Band-Reject formula
                            H(u, v) = 1 - exp(-0.5 * ((D^2 - D0^2) / (D * W))^2);
                        end
                    end
                    % Apply the filter to the shifted Fourier Transform
                    filtered_img_fft = noisy_img_fft .* H;
                    % Inverse Fourier Transform to get the filtered image in the spatial domain
                    filtered_img = real(ifft2(ifftshift(filtered_img_fft)));
                    imshow(filtered_img,'Parent',app.UIAxes3);

                case 'Butterworth notch filter'
                     % Apply Fourier Transform
                    F = fftshift(fft2(noisyImg));
                    % Get the size of the image
                   [rows, cols] = size(noisyImg);
                   crow = round(rows / 2);
                   ccol = round(cols / 2);

                   % Define default values for the Butterworth Notch Filter
                   D0 = 5;  % Default center frequency of the notch
                   n = 2;    % Default filter order

                   % Initialize the notch filter
                   notch_filter = ones(rows, cols);

                   % Loop through each pixel to apply the Butterworth formula
                   for u = 1:rows
                       for v = 1:cols
                           % Compute the distance from the notch frequencies
                           D1 = sqrt((u - crow)^2 + (v - (ccol - 30))^2);
                           D2 = sqrt((u - crow)^2 + (v - (ccol + 30))^2);

                           % Apply the Butterworth Notch Filter formula
                           notch_filter(u, v) = 1 / (1 + (D0 / D1)^(2 * n)) * ...
                               1 / (1 + (D0 / D2)^(2 * n));
                       end
                   end

                   % Apply the Butterworth Notch Filter to the shifted FFT of the image
                   F_filtered = F .* notch_filter;
                  % Inverse Fourier Transform to get the filtered image
                  filteredImg = real(ifft2(ifftshift(F_filtered)));
                  imshow(filteredImg,'Parent',app.UIAxes3);

                case 'Wiener filter'
                    w = wiener2(noisyImg,[5 5]);
                    imshow(w,'Parent',app.UIAxes3);
            end
                      
        end

        % Button pushed function: ChooseanimageButton_4
        function ChooseanimageButton_4Pushed(app, event)
             % Open file dialog to load an image
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'}, 'Select an Image');
            if file ~= 0
                % Read the image and display it in the original axes
                app.OriginalImage = imread(fullfile(path, file));
                imshow(app.OriginalImage, 'Parent', app.UIAxes9);
            end
        
        end

        % Button pushed function: EqualizationRGBButton
        function EqualizationRGBButtonPushed(app, event)
            % Plot histogram of the original image
                
                if ~isempty(app.OriginalImage)
                % Perform histogram equalization
                app.EqualizedImage = histeq(app.OriginalImage);
                
                % Display the equalized image in the second axes
                imshow(app.EqualizedImage, 'Parent', app.UIAxes10);
                
                % Plot histogram of original image
[counts, binLocations] = imhist(app.OriginalImage);
bar(app.UIAxes11, binLocations, counts);

% Plot histogram of equalized image
[counts, binLocations] = imhist(app.EqualizedImage);
bar(app.UIAxes12, binLocations, counts);
            else
                uialert(app.UIFigure, 'Please load an image first.', 'Error', 'Icon', 'error');
                end
        
        end

        % Button pushed function: ChooseanimageButton_3
        function ChooseanimageButton_3Pushed(app, event)
            % Open file dialog to load an image
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'}, 'Select an Image');
            if file ~= 0
                % Read the image and display it in the original axes
                app.OriginalImage = imread(fullfile(path, file));
                imshow(app.OriginalImage, 'Parent', app.UIAxes13);
                                title(app.UIAxes13, 'Orignal Image');

            end
        end

        % Value changed function: ChooseatransformationDropDown
        function ChooseatransformationDropDownValueChanged(app, event)
            value = app.ChooseatransformationDropDown.Value;
            if ~isempty(app.OriginalImage)
                switch value
                    case 'Flip Left to Right'
                        transformedImage = fliplr(app.OriginalImage); 
                    case 'Flip Updown'
                        transformedImage = flipud(app.OriginalImage);
                    case 'Rotate clockwise'
                       transformedImage = imrotate(app.OriginalImage, 90);
                    case 'Rotate counter clockwise'
                         transformedImage = imrotate(app.OriginalImage, -90);
                    case 'Resize to 200x200'
                        transformedImage = imresize(app.OriginalImage, [200 200]); 
                    case 'Resize to 300x300'
                        transformedImage = imresize(app.OriginalImage, [300 300]); 
                    case 'Resize to 400x400'
                        transformedImage = imresize(app.OriginalImage, [400 400]); 
                    case 'Translate 20x10'
                        transformedImage = translateImage(app.OriginalImage,app.OriginalImage ,20, 10); % Translate image 20px right and 10px down
                    otherwise
                        transformedImage = app.OriginalImage;
                end
                % Display the transformed image in the transformed axes
                imshow(transformedImage, 'Parent', app.UIAxes13_2);
                title(app.UIAxes13_2, 'Transformed Image');
            else
                uialert(app.UIFigure, 'Please load an image first.', 'Error', 'Icon', 'error');
            end 


            % Helper function for translation (method of the app)
        function translatedImage = translateImage(app, image, dx, dy)
            % Translation matrix (shift x by dx and y by dy)
            tform = affine2d([1 0 0; 0 1 0; dx dy 1]); % Translation matrix
            % Apply the transformation using imwarp
            translatedImage = imwarp(image, tform, 'OutputView', imref2d(size(image)));
        end 
        end

        % Button pushed function: chooseanimageButton_3
        function chooseanimageButton_3Pushed(app, event)

            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            app.image=imread(filename);
            imshow(app.image,'Parent',app.originalImage);
        end

        % Drop down opening function: chooseasmoothingfilterDropDown
        function chooseasmoothingfilterDropDownOpening(app, event)

        end

        % Value changed function: chooseasmoothingfilterDropDown
        function chooseasmoothingfilterDropDownValueChanged(app, event)
             J=app.image;
            if size(J,3)==3
                J=rgb2gray(J);
            end
             J=im2double(J);
                % Perform the Fourier transform
                F = fft2(double(J));
                Fshifted = fftshift(F); % Center the zero-frequency component
  
                value = app.chooseasmoothingfilterDropDown.Value;
            switch value
                case 'Gaussian'
                % Create a Gaussian low-pass filter
                [rows, cols] = size(F);
                [X, Y] = meshgrid(1:cols, 1:rows);
                D = sqrt((X - cols/2).^2 + (Y - rows/2).^2); % Distance from the center
                D0 = 30;  % Cutoff frequency
                H = exp(-(D.^2) / (2 * D0^2));  % Gaussian filter
        
                % Apply the filter in the Fourier domain
                Ffiltered = Fshifted .* H;
        
                % Transform back to spatial domain
                enhancedImage = ifft2(ifftshift(Ffiltered));
        
                % Display the enhanced image
                imshow(abs(enhancedImage), [], 'Parent', app.modifiedImage);
                 case 'Butterworth'
                 % Create a Butterworth low-pass filter
                 [rows, cols] = size(F);
                 [X, Y] = meshgrid(1:cols, 1:rows);
                 D = sqrt((X - cols/2).^2 + (Y - rows/2).^2); % Distance from the center
                 D0 = 30;  % Cutoff frequency
                 n = 2;  % Filter order
                 H = 1 ./ (1 + (D / D0).^(2 * n));  % Butterworth filter formula
        
                 % Apply the filter in the Fourier domain
                 Ffiltered = Fshifted .* H;
        
                 % Transform back to spatial domain
                 enhancedImage = ifft2(ifftshift(Ffiltered));
        
                 % Display the enhanced image
                 imshow(abs(enhancedImage), [], 'Parent', app.modifiedImage);

                case 'Ideal'
                % Create a low-pass filter (Ideal Low-Pass Filter)
                [rows, cols] = size(F);
                [X, Y] = meshgrid(1:cols, 1:rows);
                D = sqrt((X - cols/2).^2 + (Y - rows/2).^2); % Distance from the center
                cutoff = 30;  % Cutoff frequency
                H = double(D <= cutoff);  % Ideal low-pass filter mask
        
                % Apply the filter in the Fourier domain
                 Ffiltered = Fshifted .* H;
        
                % Transform back to spatial domain
                enhancedImage = ifft2(ifftshift(Ffiltered));
        
                % Display the enhanced image
                imshow(abs(enhancedImage), [], 'Parent', app.modifiedImage);
            end
        end

        % Button pushed function: TransformtoFourierDomainButton
        function TransformtoFourierDomainButtonPushed(app, event)
            % Check if the image is loaded (stored in the img property)
    if ~isempty(app.image)
        % Convert to grayscale if the image is RGB
        if size(app.image, 3) == 3
            image = rgb2gray(app.image); % Convert RGB to grayscale
        else
            image = app.image; % Already grayscale
        end
        image=im2double(image);
        
        % Perform the Fourier transform
        F = fft2(image);
        Fshifted = fftshift(F); % Center the zero-frequency component
        
        % Compute the magnitude of the Fourier transform
        magnitude = abs(Fshifted);
        
        % Logarithmic scaling of the magnitude to enhance visibility
        magnitudeLog = log(magnitude + 1);  % Add 1 to avoid log(0)
        
        % Normalize the magnitude to [0, 1] range for better display
        normalizedMagnitude = mat2gray(magnitudeLog);  % Normalize to the range [0, 1]
        
        % Display the Fourier transformed image (magnitude spectrum)
        imshow(normalizedMagnitude, [], 'Parent', app.modifiedImage_2);
    else
        disp('No image loaded.');
    end
        end

        % Value changed function: chooseasharpningfilterDropDown
        function chooseasharpningfilterDropDownValueChanged(app, event)
            J=app.image;
            if size(J,3)==3
                J=rgb2gray(J);
            end
             J=im2double(J);
                % Perform the Fourier transform
                F = fft2(double(J));
                Fshifted = fftshift(F); % Center the zero-frequency component
  
                 value = app.chooseasharpningfilterDropDown.Value;
            switch value
                case 'Gaussian'
                 % Create a Gaussian High-Pass Filter
                [rows, cols] = size(F);
                [X, Y] = meshgrid(1:cols, 1:rows);
                D = sqrt((X - cols/2).^2 + (Y - rows/2).^2); % Distance from the center
                D0 = 15;  % Cutoff frequency
                H = 1 - exp(-(D.^2) / (2 * D0^2));  % Gaussian high-pass filter
        
                % Apply the filter in the Fourier domain
                Ffiltered = Fshifted .* H;
        
                % Transform back to spatial domain
                enhancedImage = ifft2(ifftshift(Ffiltered));
        
                % Display the enhanced image
                imshow(abs(enhancedImage), [], 'Parent', app.modifiedImage);
        
                case 'Ideal'
               % Create an Ideal High-Pass Filter
               [rows, cols] = size(F);
               [X, Y] = meshgrid(1:cols, 1:rows);
               D = sqrt((X - cols/2).^2 + (Y - rows/2).^2); % Distance from the center
               cutoff = 15;  % Cutoff frequency
                H = double(D > cutoff);  % Ideal high-pass filter mask
        
               % Apply the filter in the Fourier domain
               Ffiltered = Fshifted .* H;
        
               % Transform back to spatial domain
               enhancedImage = ifft2(ifftshift(Ffiltered));
        
               % Display the enhanced image
               imshow(abs(enhancedImage), [], 'Parent', app.modifiedImage); 
            end
        end

        % Value changed function: ChooseafilterforEdgeDetectionDropDown
        function ChooseafilterforEdgeDetectionDropDownValueChanged(app, event)
             J=app.image;
            if size(J,3)==3
                J=rgb2gray(J);
            end
             J=im2double(J);
            value = app.ChooseafilterforEdgeDetectionDropDown.Value;

            switch value
                case'Laplacian'
                % Define the Laplacian kernel (3x3)
                 laplacianKernel = [0 1 0; 1 -4 1; 0 1 0];
        
                % Apply the Laplacian filter using convolution
                 edgeImage = conv2(J, laplacianKernel, 'same');
        
                 % Take the absolute value to highlight the edges
                 edgeImage = abs(edgeImage);
        
                 % Display the edge-detected image
                 imshow(edgeImage, [], 'Parent', app.modifiedImage2_2);

                case'Prewitt'
                 % Prewitt kernels for edge detection
                 Gx = [-1 0 1; -1 0 1; -1 0 1]; % Horizontal Prewitt Kernel
                 Gy = [-1 -1 -1; 0 0 0; 1 1 1]; % Vertical Prewitt Kernel
        
                 % Apply convolution to get the gradients
                 gradX = conv2(J, Gx, 'same');
                 gradY = conv2(J, Gy, 'same');
        
                % Calculate the magnitude of the gradients
                magnitude = sqrt(gradX.^2 + gradY.^2);
        
                % Display the Prewitt edge-detected image
                imshow(magnitude, [], 'Parent', app.modifiedImage2_2);

                case'Sobel'
                % Sobel kernels for edge detection
                Gx = [-1 0 1; -2 0 2; -1 0 1]; % Horizontal Sobel Kernel
                Gy = [-1 -2 -1; 0 0 0; 1 2 1]; % Vertical Sobel Kernel
        
                % Apply convolution to get the gradients
                gradX = conv2(J, Gx, 'same');
                gradY = conv2(J, Gy, 'same');
        
                % Calculate the magnitude of the gradients
                magnitude = sqrt(gradX.^2 + gradY.^2);
        
                % Display the Sobel edge-detected image
                imshow(magnitude, [], 'Parent', app.modifiedImage2_2);
                case'Laplacian of gaussian'
                % Define the standard deviation for the Gaussian filter
                sigma = 2;  % You can adjust this value to change the smoothness
        
                % Create the Laplacian of Gaussian (LoG) filter
                hsize = 5;  % Filter size (width of the kernel)
                LoGFilter = fspecial('log', hsize, sigma);  % fspecial creates the LoG filter
        
                % Apply the Laplacian of Gaussian filter to the image
                 edgeImage = imfilter(J, LoGFilter, 'same');
        
                % Display the result
                imshow(abs(edgeImage), [], 'Parent', app.modifiedImage2_2);
            end
        end

        % Button pushed function: chooseanimageButton_4
        function chooseanimageButton_4Pushed(app, event)
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            app.image=imread(filename);
            imshow(app.image,'Parent',app.originalImage2);
        end

        % Button pushed function: ApplyThresholdingButton
        function ApplyThresholdingButtonPushed(app, event)
             J=app.image;
            if size(J,3)==3
                J=rgb2gray(J);
            end
             J=im2double(J);
                % Apply Otsu's method to determine the threshold
                level = graythresh(J);
        
                % Segment the image using the threshold level
                segmentedImage = imbinarize(J, level); % Binary image
        
                % Display the segmented image (it is a binary 2D image)
                imshow(segmentedImage, [], 'Parent', app.modifiedImage2);
        end

        % Callback function
        function ConverttoGrayButtonPushed(app, event)
              % Check if the image is loaded (stored in the img property)
        if ~isempty(app.img)
        % Convert the image to grayscale if it is RGB
        if size(app.img, 3) == 3
            grayImg = rgb2gray(app.img);
        else
            grayImg = app.img; % Already grayscale
        end
        
        % Display the grayscale image in the second axes (UIAxes_2)
        imshow(grayImg, 'Parent', app.modifiedImage);
    else
        disp('No image loaded.');
    end
        end

        % Button pushed function: ConverttoGrayButton
        function ConverttoGrayButtonPushed2(app, event)
        % Check if the image is loaded (stored in the img property)
        if ~isempty(app.OriginalImage)
        % Convert the image to grayscale if it is RGB
        if size(app.OriginalImage, 3) == 3
            grayImg = rgb2gray(app.OriginalImage);
        else
            grayImg = app.image; % Already grayscale
        end
        
        % Display the grayscale image in the second axes (UIAxes_2)
        imshow(grayImg, 'Parent', app.UIAxes13_2);
    else
        disp('No image loaded.');
    end
        end

        % Button pushed function: EqualizationgrayButton
        function EqualizationgrayButtonPushed(app, event)
            % Check if the original image is loaded
if ~isempty(app.OriginalImage)
    % Convert the image to grayscale if it's not already
    grayImage = rgb2gray(app.OriginalImage);

    % Display the grayscale image in the original image axes
    imshow(grayImage, 'Parent', app.UIAxes9);
    
    % Plot histogram of the grayscale image
    [counts, binLocations] = imhist(grayImage);
    bar(app.UIAxes11, binLocations, counts);
    
    % Perform histogram equalization on the grayscale image
    app.EqualizedImage = histeq(grayImage);
    
    % Display the equalized image in the second axes
    imshow(app.EqualizedImage, 'Parent', app.UIAxes10);
    
    % Plot histogram of the equalized image
    [counts, binLocations] = imhist(app.EqualizedImage);
    bar(app.UIAxes12, binLocations, counts);
    
else
    % Show an error message if no image is loaded
    uialert(app.UIFigure, 'Please load an image first.', 'Error', 'Icon', 'error');
end
        end

        % Button pushed function: ChooseanimageButton_2
        function ChooseanimageButton_2Pushed(app, event)
            % Open file dialog to load an image
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'}, 'Select an Image');
            if file ~= 0
                % Read the image and display it in the original axes
                app.OriginalImage = imread(fullfile(path, file));
                imshow(app.OriginalImage, 'Parent', app.UIAxes14);
            end
        end

        % Value changed function: ChooseanenhansmentDropDown
        function ChooseanenhansmentDropDownValueChanged(app, event)
            selectedEnhancement = app.ChooseanenhansmentDropDown.Value;
           
            if ~isempty(app.OriginalImage)
                % Convert to grayscale if the image is RGB
                grayImage = rgb2gray(app.OriginalImage);
               imshow(uint8(grayImage), 'Parent', app.UIAxes14);

                switch selectedEnhancement
                    case 'Negative'
                        % Apply Negative enhancement
                        enhancedImage = 255 - grayImage;
                        
                   case 'Log'
                % Apply Logarithmic enhancement
                % Logarithmic transformation: s = c * log(1 + r)
                c = 30;  % Constant to adjust the scale
                enhancedImage = c * log(1 + double(grayImage));  % Ensure proper casting to double

            case 'Power Low (Brighten)'
                % Apply Power Law transformation (Darken)
                % Power Law transformation: s = c * r^gamma
                gamma = 0.4;  % Darkening effect (gamma < 1)
                enhancedImage = 255 * ((double(grayImage) / 255) .^ gamma);  % Normalize to [0, 1] and then apply gamma

            case 'Power Low (Darken)'
                % Apply Power Law transformation (Brighten)
                % Power Law transformation: s = c * r^gamma
                gamma = 2.0;  % Brightening effect (gamma > 1)
                enhancedImage = 255 * ((double(grayImage) / 255) .^ gamma);  % Normalize to [0, 1] and then apply gamma
            case 'High Contrast'
                % Apply High Contrast enhancement
                % Stretch the image to use the full range [0, 255]
                enhancedImage = imadjust(grayImage, stretchlim(grayImage), [0 1]);  % Stretch the intensity range
            case 'Low Contrast'
                % Apply Low Contrast enhancement
                % Compress the image to the middle of the intensity range [64, 191]
                enhancedImage = imadjust(grayImage, [0 1], [0.25 0.75]);  % Compress to a narrow range for low contrast
            otherwise
                % If no valid option is selected, keep the original image
                enhancedImage = grayImage;
        end
        
        % Ensure the output image is in the proper range [0, 255] and cast to uint8
        enhancedImage = uint8(min(max(enhancedImage, 0), 255));  % Clip values to [0, 255]
        
        % Display the enhanced image in the second axes
        imshow(enhancedImage, 'Parent', app.UIAxes14_2);
    else
        % Display an error message if no image is loaded
        uialert(app.UIFigure, 'Please load an image first.', 'Error', 'Icon', 'error');
    end
  

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.UIAxes13 = uiaxes(app.BasicTab);
            title(app.UIAxes13, 'Original Image')
            zlabel(app.UIAxes13, 'Z')
            app.UIAxes13_2 = uiaxes(app.BasicTab);
            title(app.UIAxes13_2, 'Transformed Image')
            zlabel(app.UIAxes13_2, 'Z')
            app.ChooseanimageButton_3 = uibutton(app.BasicTab, 'push');
            app.ChooseanimageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChooseanimageButton_3Pushed, true);
            app.ChooseanimageButton_3.Text = 'Choose an image';
            app.ChooseatransformationDropDownLabel = uilabel(app.BasicTab);
            app.ChooseatransformationDropDownLabel.Text = 'Choose a transformation';
            app.ChooseatransformationDropDown = uidropdown(app.BasicTab);
            app.ChooseatransformationDropDown.Items = {'Flip Left to Right', 'Flip Updown', 'Rotate clockwise', 'Rotate counter clockwise', 'Resize to 200x200', 'Resize to 300x300', 'Resize to 400x400', 'Translate 20x10'};
            app.ChooseatransformationDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseatransformationDropDownValueChanged, true);
            app.ChooseatransformationDropDown.Value = 'Flip Left to Right';
            app.ConverttoGrayButton = uibutton(app.BasicTab, 'push');
            app.ConverttoGrayButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoGrayButtonPushed2, true);
            app.ConverttoGrayButton.Text = 'Convert to Gray';
            app.IntensityTransformationTab = uitab(app.TabGroup);
            app.IntensityTransformationTab.Title = 'Intensity Transformation';
            app.UIAxes14 = uiaxes(app.IntensityTransformationTab);
            title(app.UIAxes14, 'Original image')
            zlabel(app.UIAxes14, 'Z')
            app.UIAxes14_2 = uiaxes(app.IntensityTransformationTab);
            title(app.UIAxes14_2, 'Transformed image')
            zlabel(app.UIAxes14_2, 'Z')
            app.ChooseanimageButton_2 = uibutton(app.IntensityTransformationTab, 'push');
            app.ChooseanimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseanimageButton_2Pushed, true);
            app.ChooseanimageButton_2.Text = 'Choose an image';
            app.ChooseanenhansmentDropDownLabel = uilabel(app.IntensityTransformationTab);
            app.ChooseanenhansmentDropDownLabel.Text = 'Choose an enhansment';
            app.ChooseanenhansmentDropDown = uidropdown(app.IntensityTransformationTab);
            app.ChooseanenhansmentDropDown.Items = {'Negative', 'Log', 'Power Low (Brighten)', 'Power Low (Darken)', 'High Contrast', 'Low Contrast'};
            app.ChooseanenhansmentDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseanenhansmentDropDownValueChanged, true);
            app.ChooseanenhansmentDropDown.Value = 'Negative';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.UIAxes9 = uiaxes(app.HistogramTab);
            title(app.UIAxes9, 'Original image')
            app.UIAxes10 = uiaxes(app.HistogramTab);
            title(app.UIAxes10, 'Equalized image')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes11 = uiaxes(app.HistogramTab);
            title(app.UIAxes11, 'Original histogram')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes12 = uiaxes(app.HistogramTab);
            title(app.UIAxes12, 'Equalized histogram')
            xlabel(app.UIAxes12, 'Frequency')
            ylabel(app.UIAxes12, 'Intensity')
            zlabel(app.UIAxes12, 'Z')
            app.ChooseanimageButton_4 = uibutton(app.HistogramTab, 'push');
            app.ChooseanimageButton_4.ButtonPushedFcn = createCallbackFcn(app, @ChooseanimageButton_4Pushed, true);
            app.ChooseanimageButton_4.Text = 'Choose an image';
            app.EqualizationRGBButton = uibutton(app.HistogramTab, 'push');
            app.EqualizationRGBButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizationRGBButtonPushed, true);
            app.EqualizationRGBButton.Text = 'Equalization RGB';
            app.EqualizationgrayButton = uibutton(app.HistogramTab, 'push');
            app.EqualizationgrayButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizationgrayButtonPushed, true);
            app.EqualizationgrayButton.Text = 'Equalization gray';
            app.SpatialdomanifiltersTab = uitab(app.TabGroup);
            app.SpatialdomanifiltersTab.Title = 'Spatial domani filters';
            app.UIAxes4 = uiaxes(app.SpatialdomanifiltersTab);
            title(app.UIAxes4, 'Original Image')
            app.UIAxes5 = uiaxes(app.SpatialdomanifiltersTab);
            title(app.UIAxes5, 'Filtered Image')
            app.chooseanimageButton = uibutton(app.SpatialdomanifiltersTab, 'push');
            app.chooseanimageButton.ButtonPushedFcn = createCallbackFcn(app, @chooseanimageButtonPushed, true);
            app.chooseanimageButton.Text = 'choose an image';
            app.chooseafilterDropDownLabel = uilabel(app.SpatialdomanifiltersTab);
            app.chooseafilterDropDownLabel.Text = 'choose a filter';
            app.chooseafilterDropDown = uidropdown(app.SpatialdomanifiltersTab);
            app.chooseafilterDropDown.Items = {'Gaussian filter', 'Laplacian filter', 'Average filter', 'Sobel filter', 'Prewitt filter'};
            app.chooseafilterDropDown.ValueChangedFcn = createCallbackFcn(app, @chooseafilterDropDownValueChanged, true);
            app.chooseafilterDropDown.Value = 'Gaussian filter';
            app.FourierdomainfiltersTab = uitab(app.TabGroup);
            app.FourierdomainfiltersTab.Title = 'Fourier domain filters';
            app.originalImage = uiaxes(app.FourierdomainfiltersTab);
            title(app.originalImage, 'Original Image')
            app.modifiedImage = uiaxes(app.FourierdomainfiltersTab);
            title(app.modifiedImage, 'Filtered Image')
            app.modifiedImage_2 = uiaxes(app.FourierdomainfiltersTab);
            title(app.modifiedImage_2, 'Frequency Spectrum')
            app.chooseanimageButton_3 = uibutton(app.FourierdomainfiltersTab, 'push');
            app.chooseanimageButton_3.ButtonPushedFcn = createCallbackFcn(app, @chooseanimageButton_3Pushed, true);
            app.chooseanimageButton_3.Text = 'choose an image';
            app.chooseasmoothingfilterDropDownLabel = uilabel(app.FourierdomainfiltersTab);
            app.chooseasmoothingfilterDropDownLabel.Text = 'choose a smoothing filter';
            app.chooseasmoothingfilterDropDown = uidropdown(app.FourierdomainfiltersTab);
            app.chooseasmoothingfilterDropDown.Items = {'Gaussian', 'Butterworth', 'Ideal'};
            app.chooseasmoothingfilterDropDown.DropDownOpeningFcn = createCallbackFcn(app, @chooseasmoothingfilterDropDownOpening, true);
            app.chooseasmoothingfilterDropDown.ValueChangedFcn = createCallbackFcn(app, @chooseasmoothingfilterDropDownValueChanged, true);
            app.chooseasmoothingfilterDropDown.Value = 'Gaussian';
            app.TransformtoFourierDomainButton = uibutton(app.FourierdomainfiltersTab, 'push');
            app.TransformtoFourierDomainButton.ButtonPushedFcn = createCallbackFcn(app, @TransformtoFourierDomainButtonPushed, true);
            app.TransformtoFourierDomainButton.Text = 'Transform to Fourier Domain';
            app.chooseasharpningfilterDropDownLabel = uilabel(app.FourierdomainfiltersTab);
            app.chooseasharpningfilterDropDownLabel.Text = 'choose a sharpning filter';
            app.chooseasharpningfilterDropDown = uidropdown(app.FourierdomainfiltersTab);
            app.chooseasharpningfilterDropDown.Items = {'Gaussian', 'Ideal'};
            app.chooseasharpningfilterDropDown.ValueChangedFcn = createCallbackFcn(app, @chooseasharpningfilterDropDownValueChanged, true);
            app.chooseasharpningfilterDropDown.Value = 'Gaussian';
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.UIAxes = uiaxes(app.NoiseTab);
            title(app.UIAxes, 'Original Image')
            app.UIAxes2 = uiaxes(app.NoiseTab);
            title(app.UIAxes2, 'Noisy Image')
            app.UIAxes3 = uiaxes(app.NoiseTab);
            title(app.UIAxes3, 'Image after removing noise')
            app.ChooseanimageButton = uibutton(app.NoiseTab, 'push');
            app.ChooseanimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseanimageButtonPushed, true);
            app.ChooseanimageButton.Text = 'Choose an image';
            app.ChooseanoiseDropDownLabel = uilabel(app.NoiseTab);
            app.ChooseanoiseDropDownLabel.Text = 'Choose a noise';
            app.ChooseanoiseDropDown = uidropdown(app.NoiseTab);
            app.ChooseanoiseDropDown.Items = {'Salt & Peppar', 'Gaussian', 'Periodic', 'Aliasing Noise'};
            app.ChooseanoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseanoiseDropDownValueChanged, true);
            app.ChooseanoiseDropDown.Value = 'Salt & Peppar';
            app.ChooseanoiseremovalDropDownLabel = uilabel(app.NoiseTab);
            app.ChooseanoiseremovalDropDownLabel.Text = 'Choose a noise removal';
            app.ChooseanoiseremovalDropDown = uidropdown(app.NoiseTab);
            app.ChooseanoiseremovalDropDown.Items = {'Median filter', 'Geometric mean filter', 'Gaussian band reject filter', 'Butterworth notch filter', 'Wiener filter'};
            app.ChooseanoiseremovalDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseanoiseremovalDropDownValueChanged, true);
            app.ChooseanoiseremovalDropDown.Value = 'Median filter';
            app.MorphologicalTab = uitab(app.TabGroup);
            app.MorphologicalTab.Title = 'Morphological';
            app.UIAxes6 = uiaxes(app.MorphologicalTab);
            title(app.UIAxes6, 'Original Image')
            app.UIAxes7 = uiaxes(app.MorphologicalTab);
            title(app.UIAxes7, 'Binary Image')
            app.UIAxes8 = uiaxes(app.MorphologicalTab);
            title(app.UIAxes8, 'Modified Binary Image ')
            app.chooseanimageButton_2 = uibutton(app.MorphologicalTab, 'push');
            app.chooseanimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @chooseanimageButton_2Pushed, true);
            app.chooseanimageButton_2.Text = 'choose an image';
            app.chooseafilterDropDown_2Label = uilabel(app.MorphologicalTab);
            app.chooseafilterDropDown_2Label.Text = 'choose a filter';
            app.chooseafilterDropDown_2 = uidropdown(app.MorphologicalTab);
            app.chooseafilterDropDown_2.Items = {'Dilation', 'Erosion', 'Opening', 'Closing'};
            app.chooseafilterDropDown_2.ValueChangedFcn = createCallbackFcn(app, @chooseafilterDropDown_2ValueChanged, true);
            app.chooseafilterDropDown_2.Value = 'Dilation';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.originalImage2 = uiaxes(app.SegmentationTab);
            title(app.originalImage2, 'Original Image')
            app.modifiedImage2 = uiaxes(app.SegmentationTab);
            title(app.modifiedImage2, 'Segmented Image')
            app.modifiedImage2_2 = uiaxes(app.SegmentationTab);
            title(app.modifiedImage2_2, 'Filtered Image')
            app.chooseanimageButton_4 = uibutton(app.SegmentationTab, 'push');
            app.chooseanimageButton_4.ButtonPushedFcn = createCallbackFcn(app, @chooseanimageButton_4Pushed, true);
            app.chooseanimageButton_4.Text = 'choose an image';
            app.ChooseafilterforEdgeDetectionDropDownLabel = uilabel(app.SegmentationTab);
            app.ChooseafilterforEdgeDetectionDropDownLabel.Text = 'Choose a filter for Edge Detection';
            app.ChooseafilterforEdgeDetectionDropDown = uidropdown(app.SegmentationTab);
            app.ChooseafilterforEdgeDetectionDropDown.Items = {'Laplacian', 'Prewitt', 'Sobel', 'Laplacian of gaussian'};
            app.ChooseafilterforEdgeDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseafilterforEdgeDetectionDropDownValueChanged, true);
            app.ChooseafilterforEdgeDetectionDropDown.Value = 'Laplacian';
            app.ApplyThresholdingButton = uibutton(app.SegmentationTab, 'push');
            app.ApplyThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyThresholdingButtonPushed, true);
            app.ApplyThresholdingButton.Text = 'Apply Thresholding';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end